classdef MetametricApp_Beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataImportTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UITable_ImportedFile            matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        Button_UseSelectedSPDs          matlab.ui.control.Button
        EditField_ImportedFileName      matlab.ui.control.EditField
        Button_ImportTable              matlab.ui.control.Button
        ChannelSelectionTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ChannelSelectionTablePanel      matlab.ui.container.Panel
        UITable_ChannelSelection        matlab.ui.control.Table
        UIAxes_ImportedSPDs             matlab.ui.control.UIAxes
        UIAxes_ChromDiagram             matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationConstraintsPanel    matlab.ui.container.Panel
        UITable_Constraints             matlab.ui.control.Table
        OptimizationGoalPanel           matlab.ui.container.Panel
        EditField_NRuns                 matlab.ui.control.NumericEditField
        NumberOfInitialGuessesToMakeEditFieldLabel  matlab.ui.control.Label
        CheckBox_HonorBounds            matlab.ui.control.CheckBox
        DropDown_MaxOrMinGoal           matlab.ui.control.DropDown
        MaximizeOrMinimizeLabel         matlab.ui.control.Label
        Button_RunOptimization          matlab.ui.control.Button
        EditField_MaxIterations         matlab.ui.control.NumericEditField
        MaxOptimizationIterationsLabel  matlab.ui.control.Label
        DropDown_MetricGoal             matlab.ui.control.DropDown
        WhichMetricLabel                matlab.ui.control.Label
        ResultsAndExportTab             matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        Button_ExportOptimization       matlab.ui.control.Button
        UITable_OptimizationResults     matlab.ui.control.Table
        UIAxes_OptimSPD                 matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel6                          matlab.ui.container.Panel
        CheckBox2                       matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        CheckBox                        matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)


    end

    properties (Access = public)
        %% Imported Raw Values
        userSPDLibrary; % Description
        XYZuserSPDLibrary;
        importedFileName_Prop;
        xyuserSPDLibrary;
        wlIntUserImported_Prop
        myUiCon
        myUiFun
        %% Selected
        ImportTableColNumSelected 
        channelSelectedTF %logical vector to designate which are selected
        %% Optimization options
        % Max/Min Goal Type
        InitialGuessChannelPercents_Prop;

        outputSavePath; % Description
        MetricsEvaluated % Description

        ChannelMetrics % Description
        wlUserImported % Description
        ChannelMetricTable

        myOptimResults = struct();
        metricResultsTable % Description

        HonorBoundsTF
        myBestOptimResult = struct();
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% debug
            %% Load previous session's output folder
            try
                app.loadMyDefaults;
            catch
            end

            %%
            hold(app.UIAxes_OptimSPD,'on');
            title(app.UIAxes_OptimSPD, "Most Recent SPD");
            xlabel(app.UIAxes_OptimSPD, "Wavelength (nm)");
            ylabel(app.UIAxes_OptimSPD, "Rad Watt / nm");
            %% UIAxes_ImportedSPDs defaults
            hold(app.UIAxes_ImportedSPDs,'on');
            app.UIAxes_ImportedSPDs.XLim = [380,780];
            defXTick = app.UIAxes_ImportedSPDs.XTick;
            app.UIAxes_ImportedSPDs.XTick = linspace(defXTick(1), defXTick(end), 2*numel(defXTick)-1 );%double ticks
            % app.UIAxes_ImportedSPDs.XTick = (380:25:780);
            grid(app.UIAxes_ImportedSPDs,'on')
            %% Make Chromaticity Diagram
            %This is just for the startup. In reality, it is deleted and
            %remade each time the library is selected from import table
            hold(app.UIAxes_ChromDiagram,'on');
            plotChromDiagram(2,app.UIAxes_ChromDiagram); %add a 2 deg chrom diagram to the axis handle
            pbaspect(app.UIAxes_ChromDiagram,[1 1 1]);
            %% Create Default Optimization Constraint Table
            [app.MetricsEvaluated] = makeUIConstraintTable(app); %helper function. It also assigns app.MetricsEvaluated property
            %% Set options for Optimization Goal Drop Downs
            app.DropDown_MetricGoal.Items = app.MetricsEvaluated;
            app.DropDown_MaxOrMinGoal.Items = {'Maximize','Minimize'};
            %% Set default max iterations
            app.EditField_MaxIterations.Value = 500;

            %%
        end

        % Button pushed function: Button_ImportTable
        function Button_ImportTablePushed(app, event)
            %% Prompt user for CSV/Excel file and convert to table
            t = promptAndReadSPDTable(app); 
            %% Store entire table into the uitable
            app.UITable_ImportedFile.Data = t;
            app.UITable_ImportedFile.ColumnName = t.Properties.VariableNames;
            app.EditField_ImportedFileName.Value = "Data Loaded From "  + string(app.importedFileName_Prop);
            %% Force app back to front
            %https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            figure(app.UIFigure);
        end

        % Button pushed function: Button_UseSelectedSPDs
        function Button_UseSelectedSPDsPushed(app, event)
            %% Based on selected cells in the UITable_ImportedFile, create the userSPDLibrary ARRAY
            [app.userSPDLibrary] = loadLibraryFromUITableSelection(app);

            %% Get xy and XYZ of all spds
            observerChoice = 2;
            [app.xyuserSPDLibrary,app.XYZuserSPDLibrary] = ...
                spdsToXyXYZ(app.userSPDLibrary(:,1:end), observerChoice );%get xy chromaticities for the imported spds
            %% Scale data Y10 = 100 for each channel
            % Find amount needed to scale each channel
%             app.ScalePerSPD = 100./app.XYZuserSPDLibrary(:,2);
            % Create new set of SPDs that are scaled to Y10 = 100;
%             app.SPDsScaled_Prop         = app.ScalePerSPD'.*app.userSPDLibrary;
            % Create new set of XYZ for them scaled. xy should not change
            % via scaling
%             [app.xySPDsScaled_Prop,app.XYZSPDsScaled_Prop] = ...
%                 spdsToXyXYZ(app.SPDsScaled_Prop(:,1:end) , 2 );%get xy chromaticities for the imported spds
            %% Set edit value text box to show file name and number of channels
            app.EditField_ImportedFileName.Value = ...
                num2str(width(app.userSPDLibrary)) + " channels were loaded from: "  + string(app.importedFileName_Prop);
            %% Plot the chrom diagram
            % clear it first
            cla(app.UIAxes_ChromDiagram);
            cla(app.UIAxes_ImportedSPDs);
            plotSPDLibraryToChromDiagramAndSPDPlot(app)

            %% Process spds and put into the selection table
            getChannelMetrics(app);
        end

        % Button pushed function: Button_RunOptimization
        function Button_RunOptimizationPushed(app, event)
            try
                %% On button push, access certain components' values
                app.HonorBoundsTF = app.CheckBox_HonorBounds.Value;
                % Make myUiFun structure
                app.myUiFun.myOptIterations = app.EditField_MaxIterations.Value; %Query edit field
                app.myUiFun.metric = string(app.DropDown_MetricGoal.Value); %Query dropdown selection
                switch app.DropDown_MaxOrMinGoal.Value %Convert from words to math. Minimizing the negative is maximizing
                    case 'Maximize'
                        app.myUiFun.minOrMax = -1;
                    case 'Minimize'
                        app.myUiFun.minOrMax = 1;
                end
                %% Make myUiCon struct from table
                app.myUiCon = table2struct(app.UITable_Constraints.Data);
                app.myUiCon = cell2struct(struct2cell(app.myUiCon),...
                    {'Metric','UseTF',...
                    'LessThanTF','LessThanVal',...
                    'EqualToTF','EqualToVal',...
                    'GreaterThanTF','GreaterThanVal'});
                %% Go through nRuns
                bestFVal = inf; %best fval is infinity, so anything is better
                app.myBestOptimResult = []; %initialize to empty
                for idxRun = 1:app.EditField_NRuns.Value
                    %% Make a random guess
                    app.InitialGuessChannelPercents_Prop = 25+75*rand(sum(app.channelSelectedTF),1);
                    %% Run optimization
                    [SpdMixOut, myOptimOptions,iterationStop,fVal(idxRun), optimizerOutput(idxRun)] = runOptimization(app);
                    %% If it is negative, then go to next attempt
%                     if min(SpdMixOut.Solution) < 0
%                         message = {'SPD Result Is Not All-Positive', 'No Data Will Be Saved Or Plotted', 'Try again'};
%                         uialert(app.UIFigure,message,'Error','Icon','Error');
% %                         app.myOptimResults(1) = []; %set to empty
%                         continue %if min is negative, then skip storing results and go to next idxRun
%                     end
                    %% Store results into one structure array
                    app.myOptimResults(idxRun).metrics = SpdMixOut;
                    app.myOptimResults(idxRun).spdPercents0 = app.InitialGuessChannelPercents_Prop;
                    app.myOptimResults(idxRun).myUiFun = app.myUiFun;
                    app.myOptimResults(idxRun).myOptimOptions = myOptimOptions;
                    app.myOptimResults(idxRun).myConstraintsTable = app.UITable_Constraints.Data;
                    app.myOptimResults(idxRun).spdFileImportPath =  app.importedFileName_Prop;
                    app.myOptimResults(idxRun).iterationStop = iterationStop;

                    
                    isValid = ...
                        min( SpdMixOut.Solution >=0) && optimizerOutput(idxRun).constrviolation < 1e-2;
                    
                    %% Update best result
                    % if the run is valid AND the optimized value is the
                    % minimum of all. note that fVal is always negative,
                    % since program always seeks negative. for maximum
                    % goals we find the minimum of the negative value.
                    if isValid ==1 && fVal(idxRun) < bestFVal
                        bestFVal = fVal(idxRun);
                        app.myBestOptimResult = app.myOptimResults(idxRun);
                    end

                end
                % clear it to debug to check it isn't used elsewhere
                app.myOptimResults = [];
                % if app.myBestOptimResult is empty, that means the if loop
                % was never entered because it was never valid
                if isempty(app.myBestOptimResult)
                    message = {'All SPD Results Violated The Constraints',...
                        'No Data Will Be Saved Or Plotted',...
                        'Try Again With HonorBounds Checked On'};
                    uialert(app.UIFigure,message,'Error','Icon','Error');
                    %                         app.myOptimResults(1) = []; %set to empty
                end

                %% write to uitable results
                metricResultsStructToTable(app);
                app.UITable_OptimizationResults.Data = app.metricResultsTable;
                %% Write spd to uiaxes
                cla(app.UIAxes_OptimSPD);
                hold(app.UIAxes_OptimSPD,'on');
                plot(app.UIAxes_OptimSPD ,...
                    app.myBestOptimResult.metrics.wl, app.myBestOptimResult.metrics.s);

                plot(app.UIAxes_OptimSPD,...
                    app.myBestOptimResult.metrics.wl,...
                    app.userSPDLibrary(:,app.channelSelectedTF).*app.myBestOptimResult.metrics.Solution',...
                    LineStyle="--");

                app.UIAxes_OptimSPD.XLim = [380,780];

                %%
            catch ME
                report = getReport(ME);
                uialert(app.UIFigure, report, 'Error Message', 'Interpreter','html')
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveMyDefaults(app);
            delete(app)
        end

        % Cell edit callback: UITable_ChannelSelection
        function UITable_ChannelSelectionCellEdit(app, event)
            %% The only editable parts of the table should be the first column
            % Therefore, no indexing is needed. Just query the selected
            app.channelSelectedTF= app.UITable_ChannelSelection.Data{:,1};
            %% Update spd plots
            % Flip is needed because for whatever reason mathworks decided
            % to have the order of children FLIPPED so that (1) calls the
            % last one. 
            % https://www.mathworks.com/matlabcentral/answers/94662-why-is-the-order-of-child-handles-reversed-when-an-object-is-queried-for-the-children-property
            [app.UIAxes_ImportedSPDs.Children(flip(~app.channelSelectedTF)).LineStyle] = deal('--');
            [app.UIAxes_ImportedSPDs.Children(flip(~app.channelSelectedTF)).LineWidth] = deal(.5);

            [app.UIAxes_ImportedSPDs.Children(flip(app.channelSelectedTF) ).LineStyle] = deal('-');
            [app.UIAxes_ImportedSPDs.Children(flip(app.channelSelectedTF)).LineWidth] = deal(2);
            %% Update cie diagram
            % Flip is not needed because we are not calling children.
            % THis method of updating is probably the smarter one
            % considering the children flipping
            currentFilledxy = findobj(app.UIAxes_ChromDiagram,...
                tag= "filledxySPDs");
            currentFilledxy.XData = app.xyuserSPDLibrary( (app.channelSelectedTF),1);
            currentFilledxy.YData = app.xyuserSPDLibrary((app.channelSelectedTF),2);
            currentFilledxy.MarkerFaceColor = [0 0.8 0]; %green
            %             currentFilledxy.
            currentFilledxy.MarkerFaceAlpha = 0.25;
            currentFilledxy.SizeData = 100;

        end

        % Button pushed function: Button_ExportOptimization
        function Button_ExportOptimizationPushed(app, event)
            %% Create set of tables
            
            myUiFunTable = struct2table(app.myBestOptimResult.myUiFun,'AsArray',true);
            if myUiFunTable.minOrMax == -1
                myUiFunTable.minOrMax = "Maximize";
            elseif myUiFunTable.minOrMax == 1
                myUiFunTable.minOrMax = "Minimize";
            else
                msgbox("Unknown goal type (min or max) when writing myUiFun to table",'Error','error');
            end

            % SPD Watts/nm
            spdTable = splitvars(table(app.myBestOptimResult.metrics.stest'));
            spdTable.Properties.VariableNames = cellstr("Wavelength(nm): " +...
                string(app.myBestOptimResult.metrics.wl) );
            % The mixture of channels used for solution
            solutionTable = splitvars(table( round(app.myBestOptimResult.metrics.Solution,2,'decimals')' ));
                %             solutionTable.Properties.VariableNames = cellstr( "Channel "...
                %                 + string( 1:numel(app.myBestOptimResult.metrics.Solution) ) + " %" );
            solutionTable.Properties.VariableNames = app.UITable_ImportedFile.ColumnName(app.channelSelectedTF);
            % The initial guess percent for each channel
            initialGuessTable = splitvars(table(app.myBestOptimResult.metrics.SpdPercents0') );
                %             initialGuessTable.Properties.VariableNames = cellstr( "Channel " +...
                %                 string( 1:numel(app.myBestOptimResult.metrics.SpdPercents0)) + " Initial Guess %") ;
            initialGuessTable.Properties.VariableNames = app.UITable_ImportedFile.ColumnName(app.channelSelectedTF) + " Initial Guess %";
            % How many iterations were used for optimization run
            iterationUsedTable = table(app.myBestOptimResult.metrics.IterationStop);
            iterationUsedTable.Properties.VariableNames = "Iterations Used";
            %% We have all of our tables as 1 row and many variables. Merge them
            myTableOneCol = [iterationUsedTable, myUiFunTable, solutionTable,...
                initialGuessTable, spdTable];
            %% Make each run correspond to column instead of a row
            % SPDs are usually written column-wise
            myTableOneCol = rows2vars(myTableOneCol);
            % By default it make variable names a column on its own. Make
            % and assign rownames of table to the variable names then
            % delete the unused column of variable names now that they are
            % row namesmyTableOneCol
            myTableOneCol.Properties.RowNames= myTableOneCol.OriginalVariableNames;
            myTableOneCol.OriginalVariableNames = [];
            %% Get optimization settings to put into a third sheet
            myProps = properties(app.myBestOptimResult.myOptimOptions);
            for prop = 1:numel(myProps)
                optionStruct.(myProps{prop}) = app.myBestOptimResult.myOptimOptions.(myProps{prop});
            end
            optionTable = splitvars( struct2table(optionStruct,'asarray',true))  ;
            optionTable.TypicalX = [];
            typicalXTable= splitvars(table(app.myBestOptimResult.myOptimOptions.TypicalX') );
            typicalXTable.Properties.VariableNames = "Typical X, CH " + string(1:numel(app.myBestOptimResult.myOptimOptions.TypicalX) );

            optionTable = rows2vars( [optionTable, typicalXTable] ) ;
            %% Ask user where to save
            % This code is very similar to the one used to import the data
            exportFileTypes = {'*.xls;*.xlsx;*.xlsb;*.xlsm;*.csv',...
                'SPD Files (*.xls,*.xlsx,*.xlsb,*.xlsm,*.csv)'};
            [outputFileName, outputPathName] = uiputfile(exportFileTypes);
            app.outputSavePath = fullfile(outputPathName,outputFileName);
            %% refocus back to app
            %https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            figure(app.UIFigure);
            %% If file exists, load existing runs as table and append this run to it and resave
            % Note that we output 2 sheets. First sheet is the metrics per
            % run and 2nd sheet is the metric constraints table.

            switch isfile(app.outputSavePath)
                case 0 %file does not exist yet
                    %write out merged table
                    writetable(app.metricResultsTable,...
                        app.outputSavePath, 'Sheet',1);
                    myTableOneCol.Properties.VariableNames = "Best SPD";
                    writetable(myTableOneCol,...
                        app.outputSavePath, 'Sheet',2,'WriteRowNames',true,...
                        'WriteVariableNames',true);

                    writetable(app.myBestOptimResult.myConstraintsTable,...
                        app.outputSavePath, 'Sheet',3);

                    writetable(optionTable,...
                        app.outputSavePath, 'Sheet',4);

                case 1 %it exists and is a file
                    
                    %perhaps warn that it will be rewritten?
                otherwise
                    msgbox("Unexpected case when checking is the output file already exists",'Error','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.GridLayout3 = uigridlayout(app.DataImportTab);
            app.Panel = uipanel(app.GridLayout3);
            app.Button_ImportTable = uibutton(app.Panel, 'push');
            app.Button_ImportTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportTablePushed, true);
            app.Button_ImportTable.Text = 'Import Table';
            app.EditField_ImportedFileName = uieditfield(app.Panel, 'text');
            app.EditField_ImportedFileName.Placeholder = 'Imported File Name';
            app.Button_UseSelectedSPDs = uibutton(app.Panel, 'push');
            app.Button_UseSelectedSPDs.ButtonPushedFcn = createCallbackFcn(app, @Button_UseSelectedSPDsPushed, true);
            app.Button_UseSelectedSPDs.Text = 'Use Selected SPDs';
            app.UITable_ImportedFile = uitable(app.GridLayout3);
            app.UITable_ImportedFile.RowName = {};
            app.ChannelSelectionTab = uitab(app.TabGroup);
            app.ChannelSelectionTab.Title = 'Channel Selection';
            app.GridLayout = uigridlayout(app.ChannelSelectionTab);
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.ChannelSelectionTablePanel = uipanel(app.GridLayout);
            app.ChannelSelectionTablePanel.Title = 'Channel Selection Table';
            app.UITable_ChannelSelection = uitable(app.ChannelSelectionTablePanel);
            app.UITable_ChannelSelection.RowName = {};
            app.UITable_ChannelSelection.CellEditCallback = createCallbackFcn(app, @UITable_ChannelSelectionCellEdit, true);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.GridLayout2 = uigridlayout(app.OptimizationTab);
            app.OptimizationGoalPanel = uipanel(app.GridLayout2);
            app.OptimizationGoalPanel.Title = 'Optimization Goal';
            app.WhichMetricLabel = uilabel(app.OptimizationGoalPanel);
            app.WhichMetricLabel.Text = 'Which Metric?';
            app.DropDown_MetricGoal = uidropdown(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel = uilabel(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel.Text = 'Max Optimization Iterations';
            app.EditField_MaxIterations = uieditfield(app.OptimizationGoalPanel, 'numeric');
            app.EditField_MaxIterations.ValueDisplayFormat = '%.0f';
            app.Button_RunOptimization = uibutton(app.OptimizationGoalPanel, 'push');
            app.Button_RunOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_RunOptimizationPushed, true);
            app.Button_RunOptimization.Text = 'Run Optimization';
            app.MaximizeOrMinimizeLabel = uilabel(app.OptimizationGoalPanel);
            app.MaximizeOrMinimizeLabel.Text = 'Maximize Or Minimize?';
            app.DropDown_MaxOrMinGoal = uidropdown(app.OptimizationGoalPanel);
            app.CheckBox_HonorBounds = uicheckbox(app.OptimizationGoalPanel);
            app.CheckBox_HonorBounds.Text = {'Honor Bounds? '; '(Allow negative mix %)'};
            app.NumberOfInitialGuessesToMakeEditFieldLabel = uilabel(app.OptimizationGoalPanel);
            app.NumberOfInitialGuessesToMakeEditFieldLabel.Text = 'Number Of Initial Guesses To Make';
            app.EditField_NRuns = uieditfield(app.OptimizationGoalPanel, 'numeric');
            app.OptimizationConstraintsPanel = uipanel(app.GridLayout2);
            app.OptimizationConstraintsPanel.Title = 'Optimization Constraints';
            app.UITable_Constraints = uitable(app.OptimizationConstraintsPanel);
            app.UITable_Constraints.RowName = {};
            app.ResultsAndExportTab = uitab(app.TabGroup);
            app.ResultsAndExportTab.Title = 'Results And Export';
            app.Panel_2 = uipanel(app.ResultsAndExportTab);
            app.UIAxes_OptimSPD = uiaxes(app.Panel_2);
            title(app.UIAxes_OptimSPD, 'Optimized SPD')
            xlabel(app.UIAxes_OptimSPD, 'X')
            ylabel(app.UIAxes_OptimSPD, 'Y')
            zlabel(app.UIAxes_OptimSPD, 'Z')
            app.UITable_OptimizationResults = uitable(app.Panel_2);
            app.UITable_OptimizationResults.RowName = {};
            app.Button_ExportOptimization = uibutton(app.Panel_2, 'push');
            app.Button_ExportOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportOptimizationPushed, true);
            app.Button_ExportOptimization.Text = 'Export Optimization';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable2 = uitable(app.GridLayout4);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.CheckBox = uicheckbox(app.GridLayout4);
            app.Panel6 = uipanel(app.GridLayout4);
            app.Panel6.Title = 'Panel6';
            app.UIAxes2 = uiaxes(app.Panel6);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.Panel6);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel6);
            app.CheckBox2 = uicheckbox(app.Panel6);
            app.CheckBox2.Text = 'Check Box2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetametricApp_Beta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end